classdef compiled_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CRTNCalculatorUIFigure  matlab.ui.Figure
        HTML                    matlab.ui.control.HTML
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            htmlStrings = [
                "<!DOCTYPE html>"
                "<html lang='en'>"
                "    <head>"
                "        <meta charset='UTF-8'>"
                "        <meta name='viewport' content='width=device-width, initial-scale=1.0'>"
                "        <title>CRTN Calculator</title>"
                "        <style>"
                "            @font-face {"
                "                font-family: Inter;"
                "                font-style: normal;"
                "                font-weight: 100 900;"
                "                font-display: swap;"
                "                src: url(data:application/octet-stream;base64,) format('woff2');"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
                "            }"
                ""
                "            :root {"
                "                --bg-1: #111111;"
                "                --bg-2: #1c1c1c;"
                "                --bg-3: #2a2a2a;"
                "                --fg-1: #ededed;"
                "                --fg-2: #a1a1aa;"
                "                --bd: #333333;"
                "                --e: #ff5252;"
                "                --w: #ffe652;"
                "                --s: #52ffb3;"
                "                --i: #2fbffd;"
                "            }"
                ""
                "            * {"
                "                font-family: 'Inter', sans-serif;"
                "                transition: .125s ease-in-out;"
                "                box-sizing: border-box;"
                "            }"
                ""
                "            body {"
                "                background: #000; "
                "                display: flex;"
                "                align-items: center;"
                "                justify-content: center;"
                "                min-height: 100vh;"
                "                margin: 0;"
                "                color: var(--fg-1);"
                "            }"
                ""
                "            .content-container {"
                "                display: flex;"
                "                width: 90%;"
                "                padding: 1rem 2rem 0;"
                "            }"
                ""
                "            .calculator-container {"
                "                border-radius: 1rem;"
                "                width: 100%;"
                "                padding: 1rem 2rem 0;"
                "            }"
                ""
                "            .header {"
                "                text-align: center;"
                "                margin-bottom: 1rem;"
                "            }"
                ""
                "            .header h1 {"
                "                margin: 0;"
                "                font-size: 2rem;"
                "            }"
                ""
                "            .header p {"
                "                color: var(--fg-2);"
                "                margin: .5rem 0 0 0;"
                "            }"
                ""
                "            .input-header {"
                "                color: var(--i);"
                "            }"
                ""
                "            .output-header {"
                "                color: var(--s);"
                "            }"
                ""
                "            .grid-container {"
                "                display: grid;"
                "                grid-template-columns: 1fr 1fr 1fr;"
                "                gap: 2rem;"
                "            }"
                ""
                "            .parameter-row {"
                "                display: flex;"
                "                justify-content: space-between;"
                "                align-items: center;"
                "                padding: .5rem 0;"
                "                border-bottom: 1px solid var(--bd);"
                "            }"
                ""
                "            .parameter-row label {"
                "                font-size: .9rem;"
                "                color: var(--fg-1);"
                "            }"
                ""
                "            input[type='number'], .output-box {"
                "                background: var(--bg-2);"
                "                border: 1px solid var(--bd);"
                "                color: var(--fg-1);"
                "                border-radius: .5rem;"
                "                padding: .5rem;"
                "                width: 5rem;"
                "                height: 2.2rem;"
                "                text-align: right;"
                "                font-weight: 600;"
                "            }"
                ""
                "            input[type='number']:hover {"
                "                border-color: var(--i);"
                "                background: var(--bg-3);"
                "            }"
                ""
                "            input[type='number']:focus {"
                "                outline: none;"
                "                border-color: var(--i);"
                "            }"
                ""
                "            input::-webkit-outer-spin-button,"
                "            input::-webkit-inner-spin-button {"
                "                -webkit-appearance: none;"
                "                margin: 0;"
                "            }"
                ""
                "            input[type=number] {"
                "                appearance: textfield;"
                "                -moz-appearance: textfield;"
                "            }"
                ""
                "            .output-col .output-box {"
                "                border: 1px solid var(--bd);"
                "                background: transparent;"
                "                display: inline-block;"
                "                cursor: pointer;"
                "            }"
                ""
                "            .output-col .output-box:hover {"
                "                border-color: var(--s);"
                "                background: var(--bg-2);"
                "            }"
                ""
                "            .parameter-row > div {"
                "                display: flex;"
                "                align-items: center;"
                "            }"
                ""
                "            .unit {"
                "                font-size: .8rem;"
                "                color: var(--fg-2);"
                "                margin-left: .5rem;"
                "                width: 2rem;"
                "                display: inline-block;"
                "            }"
                ""
                "            .input-col .unit {"
                "                width: 6rem; "
                "            }"
                "            .custom-toggle {"
                "                display: flex;"
                "                background: var(--bg-1);"
                "                border: 1px solid var(--bd);"
                "                border-radius: .5rem;"
                "                margin-left: .5rem;"
                "                overflow: hidden;"
                "                width: 6rem;"
                "                height: 2.2rem;"
                "            }"
                ""
                "            .toggle-input {"
                "                display: none;"
                "            }"
                ""
                "            .toggle-label {"
                "                flex: 1;"
                "                display: flex;"
                "                align-items: center;"
                "                justify-content: center;"
                "                font-size: .75rem;"
                "                color: var(--fg-2);"
                "                cursor: pointer;"
                "                user-select: none;"
                "                transition: .15s ease-in-out;"
                "            }"
                ""
                "            .toggle-label:hover {"
                "                background: var(--bg-3);"
                "                color: var(--fg-1);"
                "            }"
                ""
                "            .toggle-input:checked + .toggle-label {"
                "                background: var(--bg-3);"
                "                color: var(--fg-1);"
                "            }"
                ""
                "            .output-db {"
                "                color: var(--s);"
                "                font-size: 1.1rem;"
                "                font-weight: 700;"
                "            }"
                ""
                "            input.invalid {"
                "                border-color: var(--e);"
                "                background-color: color-mix(in srgb, var(--e) 15%, transparent);"
                "            }"
                ""
                "            input.invalid:focus, input.invalid:hover {"
                "                border-color: var(--e);"
                "                background-color: color-mix(in srgb, var(--e) 25%, transparent);"
                "            }"
                ""
                "            .debug-header {"
                "                color: var(--i);"
                "                margin: 2rem 0 .5rem;"
                "                font-size: 1rem;"
                "            }"
                ""
                "            .debug-console {"
                "                background: #000;"
                "                border: 1px solid var(--bd);"
                "                border-radius: .5rem;"
                "                padding: .75rem;"
                "                color: var(--s);"
                "                font-family: monospace;"
                "                font-size: .75rem;"
                "                height: 6rem;"
                "                overflow-y: auto;"
                "                margin: 0;"
                "                white-space: pre-wrap;"
                "                word-wrap: break-word;"
                "            }"
                ""
                "            .custom-select {"
                "                position: relative;"
                "                width: 11.5rem; "
                "                height: 2.2rem;"
                "                font-size: .8rem;"
                "                user-select: none;"
                "            }"
                ""
                "            .select-selected {"
                "                background: var(--bg-2);"
                "                border: 1px solid var(--bd);"
                "                color: var(--fg-1);"
                "                border-radius: .5rem;"
                "                padding: 0 .5rem;"
                "                height: 100%;"
                "                display: flex;"
                "                align-items: center;"
                "                cursor: pointer;"
                "            }"
                ""
                "            .select-selected:hover {"
                "                border-color: var(--i);"
                "                background: var(--bg-3);"
                "            }"
                ""
                "            .select-selected::after {"
                "                content: '▼';"
                "                font-size: .6rem;"
                "                margin-left: auto;"
                "                color: var(--fg-2);"
                "            }"
                ""
                "            .select-items {"
                "                position: absolute;"
                "                background: var(--bg-2);"
                "                border: 1px solid var(--bd);"
                "                border-radius: .5rem;"
                "                top: 115%;"
                "                left: 0;"
                "                right: 0;"
                "                z-index: 99;"
                "                overflow: hidden;"
                "                box-shadow: 0 4px 12px #00000080;"
                "            }"
                ""
                "            .select-hide {"
                "                display: none;"
                "            }"
                ""
                "            .select-items div {"
                "                color: var(--fg-1);"
                "                padding: .5rem;"
                "                cursor: pointer;"
                "                border-bottom: 1px solid var(--bg-1);"
                "            }"
                ""
                "            .select-items div:hover {"
                "                background: var(--bg-3);"
                "                color: var(--i);"
                "            }"
                ""
                "            .select-items div:last-child {"
                "                border-bottom: none;"
                "            }"
                "        </style>"
                "        <!-- Attempt to prevent CEF caching in MATLAB -->"
                "        <meta http-equiv='Cache-Control' content='no-cache, no-store, must-revalidate'>"
                "        <meta http-equiv='Pragma' content='no-cache'>"
                "        <meta http-equiv='Expires' content='0'>"
                "    </head>"
                "    <body>"
                "        <div class='content-container'>"
                "            <div class='calculator-container'>"
                "                <div class='header'>"
                "                    <h1>CRTN Calculator</h1>"
                "                    <p>Road Noise Assessment Tool</p>"
                "                </div>"
                "                <div class='grid-container'>"
                "                    <div class='input-col'>"
                "                        <h3 class='input-header'>Traffic Data</h3>"
                "                        <div class='parameter-row'>"
                "                            <label>Vehicles observed</label>"
                "                            <div><input type='number' value='' id='totalVehicles'><span class='unit'></span></div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>HGVs observed</label>"
                "                            <div><input type='number' value='' id='hgvCount'><span class='unit'></span></div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>Time observed</label>"
                "                            <div>"
                "                                <input type='number' value='' id='timeObserved'>"
                "                                <div class='custom-toggle'>"
                "                                    <input type='radio' id='unit-mins' name='timeUnit' value='mins' class='toggle-input' checked>"
                "                                    <label for='unit-mins' class='toggle-label'>mins</label>"
                "                                    <input type='radio' id='unit-hours' name='timeUnit' value='hours' class='toggle-input'>"
                "                                    <label for='unit-hours' class='toggle-label'>hrs</label>"
                "                                </div>"
                "                            </div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>Speed</label>"
                "                            <div>"
                "                                <input type='number' value='' id='velocity'>"
                "                                <div class='custom-toggle'>"
                "                                    <input type='radio' id='unit-kph' name='speedUnit' value='kph' class='toggle-input' checked>"
                "                                    <label for='unit-kph' class='toggle-label'>km/h</label>"
                "                                    <input type='radio' id='unit-mph' name='speedUnit' value='mph' class='toggle-input'>"
                "                                    <label for='unit-mph' class='toggle-label'>mph</label>"
                "                                </div>"
                "                            </div>"
                "                        </div>"
                "                    </div>"
                "                    <div class='input-col'>"
                "                        <h3 class='input-header'>Site & Environment</h3>"
                "                        <div class='parameter-row'>"
                "                            <label>Distance to road</label>"
                "                            <div>"
                "                                <input type='number' value='' id='distance'>"
                "                                <div class='custom-toggle'>"
                "                                    <input type='radio' id='unit-meters' name='distanceUnit' value='meters' class='toggle-input' checked>"
                "                                    <label for='unit-meters' class='toggle-label'>m</label>"
                "                                    <input type='radio' id='unit-feet' name='distanceUnit' value='feet' class='toggle-input'>"
                "                                    <label for='unit-feet' class='toggle-label'>ft</label>"
                "                                </div>"
                "                            </div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>Angle of view</label>"
                "                            <div><input type='number' value='' id='angle'><span class='unit'>deg</span></div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>Road gradient</label>"
                "                            <div><input type='number' value='' id='gradient'><span class='unit'>%</span></div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>Road surface</label>"
                "                            <div>"
                "                                <div class='custom-select' id='surfaceSelect' data-value='impervious'>"
                "                                    <div class='select-selected'>Impervious</div>"
                "                                    <div class='select-items select-hide'>"
                "                                        <div data-val='impervious'>Impervious</div>"
                "                                        <div data-val='pervious'>Pervious</div>"
                "                                        <div data-val='concrete'>Concrete</div>"
                "                                    </div>"
                "                                </div>"
                "                            </div>"
                "                        </div>"
                "                        <div class='parameter-row parameter-row-select'>"
                "                            <label>Barrier screening</label>"
                "                            <div>"
                "                                <div class='custom-select' id='barrierSelect' data-value='0'>"
                "                                    <div class='select-selected'>Direct (0 dB)</div>"
                "                                    <div class='select-items select-hide'>"
                "                                        <div data-val='0'>Direct (0 dB)</div>"
                "                                        <div data-val='-5'>Screened (-5 dB)</div>"
                "                                        <div data-val='-10'>Well Screened (-10 dB)</div>"
                "                                    </div>"
                "                                </div>"
                "                            </div>"
                "                        </div>"
                "                        <div class='parameter-row parameter-row-select'>"
                "                            <label>Ground type</label>"
                "                            <div>"
                "                                <div class='custom-select' id='groundSelect' data-value='0'>"
                "                                    <div class='select-selected'>Hard (0)</div>"
                "                                    <div class='select-items select-hide'>"
                "                                        <div data-val='0'>Hard (0)</div>"
                "                                        <div data-val='0.5'>Mixed (0.5)</div>"
                "                                        <div data-val='1'>Soft (1.0)</div>"
                "                                    </div>"
                "                                </div>"
                "                            </div>"
                "                        </div>"
                "                    </div>"
                "                    <div class='output-col'>"
                "                        <h3 class='output-header'>Calculations</h3>"
                "                        <div class='parameter-row'>"
                "                            <label>Vehicles per hour</label>"
                "                            <div><span class='output-box' id='vph'>JS_ERR</span><span class='unit'></span></div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>HGV percentage</label>"
                "                            <div><span class='output-box' id='pctHgv'>JS_ERR</span><span class='unit'>%</span></div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>CRTN calculation</label>"
                "                            <div><span class='output-box' id='crtnCalc'>JS_ERR</span><span class='unit'>dB</span></div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>HGV & speed adjustments</label>"
                "                            <div><span class='output-box' id='corrSpeedHgv'>JS_ERR</span><span class='unit'>dB</span></div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label>Site & environment adjustments</label>"
                "                            <div><span class='output-box' id='envCorr'>JS_ERR</span><span class='unit'>dB</span></div>"
                "                        </div>"
                "                        <div class='parameter-row'>"
                "                            <label class='output-db'>Corrected level</label>"
                "                            <div><span class='output-box output-db' id='corrLevel'>JS_ERR</span><span class='unit'>dB</span></div>"
                "                        </div>"
                "                    </div>"
                "                </div>"
                "                <!-- Enable debugger for detailed breakdown of corrections -->"
                "                <h3 class='debug-header'>Console</h3>"
                "                <div id='debug-log' class='debug-console'></div>"
                "            </div>"
                "        </div>"
                "        <script>"
                "            const inputs = document.querySelectorAll('input[type=\'number\']');"
                "            const toggles = document.querySelectorAll('.toggle-input');"
                "            const outputs = document.querySelectorAll('.output-box');"
                ""
                "            let matlabObj = null;"
                ""
                "            function logDebug(message) {"
                "                const logger = document.getElementById('debug-log');"
                "                if(logger) {"
                "                    const time = new Date().toLocaleTimeString();"
                "                    logger.textContent = `[${time}] ${message}\n` + logger.textContent;"
                "                }"
                "                // return; // Disable logging"
                "            }"
                ""
                "            // Catch connection from HTML to MATLAB"
                "            function connectToMatlab(htmlComponent) {"
                "                matlabObj = htmlComponent;"
                "                logDebug('✔️ | APP -> MATLAB | Bridge connected.');"
                ""
                "                matlabObj.addEventListener('DataChanged', function(event) {"
                "                    const results = event.Data;"
                "                    logDebug('✔️ | APP <- MATLAB | Received from backend: \n\n' + JSON.stringify(results) + '\n');"
                "                    "
                "                    if (results.action === 'updateOutputs') {"
                "                        try {"
                "                            document.getElementById('vph').innerText = Number(results.vph).toFixed(0);"
                "                            document.getElementById('pctHgv').innerText = Number(results.pctHgv).toFixed(2);"
                "                            document.getElementById('corrSpeedHgv').innerText = Number(results.corrSpeedHgv).toFixed(2);"
                "                            document.getElementById('crtnCalc').innerText = Number(results.crtnCalc).toFixed(2);"
                ""
                "                            // Sum all corrections for site & environment correction value"
                "                            const envCorr = "
                "                                results.angCorr + "
                "                                results.gradCorr + "
                "                                results.distCorr + "
                "                                results.surfCorr + "
                "                                results.groundCorr + "
                "                                results.barrierCorr;"
                ""
                "                            document.getElementById('envCorr').innerText = Number(envCorr).toFixed(2);"
                "                            "
                "                            // Commenting out individual corrections for now to simplify UI, but can be re-enabled if needed"
                "                            // document.getElementById('angCorr').innerText = Number(results.angCorr).toFixed(2);"
                "                            // document.getElementById('gradCorr').innerText = Number(results.gradCorr).toFixed(2);"
                "                            // document.getElementById('distCorr').innerText = Number(results.distCorr).toFixed(2);"
                "                            // document.getElementById('surfCorr').innerText = Number(results.surfCorr).toFixed(2);"
                "                            // document.getElementById('groundCorr').innerText = Number(results.groundCorr).toFixed(2);"
                "                            // document.getElementById('barrierCorr').innerText = Number(results.barrierCorr).toFixed(2);"
                "                            "
                "                            document.getElementById('corrLevel').innerText = Number(results.corrLevel).toFixed(2);"
                "                            logDebug('✔️ | Updated successfully.');"
                "                        }catch (err) {"
                "                            logDebug('❌ | Failed to update UI: ' + err.message);"
                "                            document.getElementById('corrLevel').innerText = 'Error';"
                "                        }"
                "                    }"
                "                });"
                ""
                "                validateInputs();"
                "            }"
                ""
                "            document.querySelectorAll('.custom-select').forEach(select => {"
                "                const selected = select.querySelector('.select-selected');"
                "                const items = select.querySelector('.select-items');"
                "                const options = select.querySelectorAll('.select-items div');"
                ""
                "                // Toggle dropdown"
                "                selected.addEventListener('click', function(e) {"
                "                    e.stopPropagation();"
                "                    // Close any other open dropdowns first"
                "                    document.querySelectorAll('.select-items').forEach(el => {"
                "                        if (el !== items) el.classList.add('select-hide');"
                "                    });"
                "                    items.classList.toggle('select-hide');"
                "                });"
                ""
                "                // Handle option click"
                "                options.forEach(option => {"
                "                    option.addEventListener('click', function() {"
                "                        selected.innerText = this.innerText;"
                "                        select.setAttribute('data-value', this.getAttribute('data-val'));"
                "                        items.classList.add('select-hide');"
                "                        validateInputs(); // Re-calculate when changed"
                "                    });"
                "                });"
                "            });"
                ""
                "            // Close dropdowns if clicking outside"
                "            document.addEventListener('click', () => {"
                "                document.querySelectorAll('.select-items').forEach(el => el.classList.add('select-hide'));"
                "            });"
                ""
                "            function validateInputs() {"
                "                let isValid = true;"
                "                "
                "                const totalVehicles = parseFloat(document.getElementById('totalVehicles').value);"
                "                const hgvCount = parseFloat(document.getElementById('hgvCount').value);"
                "                const timeObserved = parseFloat(document.getElementById('timeObserved').value);"
                "                const velocity = parseFloat(document.getElementById('velocity').value);"
                "                const angle = parseFloat(document.getElementById('angle').value);"
                "                const distance = parseFloat(document.getElementById('distance').value);"
                "                const gradient = parseFloat(document.getElementById('gradient').value);"
                ""
                "                const setInvalid = (id) => {"
                "                    document.getElementById(id).classList.add('invalid');"
                "                    isValid = false;"
                "                };"
                "                const clearInvalid = (id) => {"
                "                    document.getElementById(id).classList.remove('invalid');"
                "                };"
                ""
                "                inputs.forEach(input => clearInvalid(input.id));"
                ""
                "                // Validation checks"
                "                if (isNaN(totalVehicles) || totalVehicles < 0) setInvalid('totalVehicles');"
                "                if (isNaN(hgvCount) || hgvCount < 0 || hgvCount > totalVehicles) setInvalid('hgvCount');"
                "                if (isNaN(timeObserved) || timeObserved <= 0) setInvalid('timeObserved');"
                "                if (isNaN(velocity) || velocity <= 0) setInvalid('velocity');"
                "                if (isNaN(angle) || angle <= 0 || angle > 180) setInvalid('angle');"
                "                if (isNaN(distance) || distance < 0) setInvalid('distance');"
                "                if (isNaN(gradient) || gradient < 0 || gradient > 100) setInvalid('gradient');"
                ""
                "                if (!isValid) {"
                "                    outputs.forEach(output => { output.innerText = '----'; });"
                "                } else {"
                "                    outputs.forEach(output => {"
                "                        if (output.innerText === '----') output.innerText = '0.00'; "
                "                    });"
                "                    "
                "                    // Send data out to MATLAB"
                "                    if (matlabObj !== null) {"
                "                        const payload = {"
                "                            action: 'calculate',"
                "                            totalVehicles: totalVehicles,"
                "                            hgvCount: hgvCount,"
                "                            timeObserved: timeObserved,"
                "                            timeUnit: document.querySelector('input[name=\'timeUnit\']:checked').value,"
                "                            velocity: velocity,"
                "                            speedUnit: document.querySelector('input[name=\'speedUnit\']:checked').value,"
                "                            angle: angle,"
                "                            distance: distance,"
                "                            distanceUnit: document.querySelector('input[name=\'distanceUnit\']:checked').value,"
                "                            gradient: gradient,"
                "                            barrierCorrection: parseFloat(document.getElementById('barrierSelect').getAttribute('data-value')),"
                "                            groundType: parseFloat(document.getElementById('groundSelect').getAttribute('data-value')),"
                "                            roadSurface: document.getElementById('surfaceSelect').getAttribute('data-value')"
                "                        };"
                "                        "
                "                        logDebug('✔️ | APP -> MATLAB | Sending to backend...');"
                "                        matlabObj.Data = payload;"
                "                    }"
                "                }"
                "            }"
                ""
                "            inputs.forEach(input => { input.addEventListener('input', validateInputs); });"
                "            toggles.forEach(toggle => { toggle.addEventListener('change', validateInputs); });"
                "        </script>"
                "        <script>"
                "            function setup(htmlComponent) {"
                "                connectToMatlab(htmlComponent);"
                "            }"
                "        </script>"
                "    </body>"
                "</html>"
            ];
            app.HTML.HTMLSource = strjoin(htmlStrings, newline);
        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
            data = event.Data;
                        
            % If JS on site has requested calculation
            if isfield(data, "action") && strcmp(data.action, "calculate")
                
                % 1. Extract inputs
                q_total = data.totalVehicles;
                q_hgv = data.hgvCount;
                t_obs = data.timeObserved;
                t_unit = data.timeUnit;
                v = data.velocity;
                v_unit = data.speedUnit;
                angle = data.angle;
                distance = data.distance;
                dist_unit = data.distanceUnit;
                gradient = data.gradient;
                barrier_val = data.barrierCorrection;
                ground_val = data.groundType;
                surface_type = data.roadSurface;
            
                % 2. Convert units for standardisation
                if strcmp(t_unit, "mins")
                    time_hours = t_obs / 60;
                else
                    time_hours = t_obs;
                end
                
                if strcmp(v_unit, "mph")
                    v_kph = v * 1.60934;
                else
                    v_kph = v;
                end
                
                if strcmp(dist_unit, "feet")
                    dist_m = distance * 0.3048;
                else
                    dist_m = distance;
                end
                
                % 3. Calculate CRTN values
                vph = q_total / time_hours;
                pctHgv = (q_hgv / q_total) * 100;
                
                % Base calculations
                crtnCalc = 42.2 + 10 * log10(vph);
                corrSpeedHgv = 33 * log10(v_kph + 40 + (500 / v_kph)) + 10 * log10(1 + (5 * pctHgv / v_kph)) - 68.8;
                angCorr = 10 * log10(angle / 180);
                
                % Environmental corrections 
                gradCorr = 0.3 * gradient;
                
                % Distance correction
                if dist_m >= 3.5
                    distCorr = -10 * log10(dist_m / 13.5); 
                else
                    distCorr = 0;
                end
                
                % Surface correction
                switch surface_type
                    case "impervious"
                        if v_kph >= 75
                            surfCorr = 0;
                        else
                            surfCorr = -1; % Standard for speeds < 75 km/h
                        end
                    case "pervious"
                        surfCorr = -3.5;
                    case "concrete"
                        surfCorr = 1;
                    otherwise
                        surfCorr = 0;
                end
                
                % Ground & Barrier corrections
                groundCorr = ground_val;
                barrierCorr = barrier_val; 
                
                % Calculate Final Corrected Level
                corrLevel = crtnCalc + corrSpeedHgv + angCorr + gradCorr + distCorr + surfCorr + groundCorr + barrierCorr;
                
                % 4. Package the results
                results = struct();
                results.action = "updateOutputs";
                results.vph = vph;
                results.pctHgv = pctHgv;
                results.crtnCalc = crtnCalc;
                results.corrSpeedHgv = corrSpeedHgv;
                results.angCorr = angCorr;
                results.gradCorr = gradCorr;
                results.distCorr = distCorr;
                results.surfCorr = surfCorr;
                results.groundCorr = groundCorr;
                results.barrierCorr = barrierCorr;
                results.corrLevel = corrLevel;
                
                % 5. Send the results back to the HTML component
                disp("Calculations complete. Sending back to webpage...");
                    
                app.HTML.Data = results;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CRTNCalculatorUIFigure = uifigure('Visible', 'off');
            app.CRTNCalculatorUIFigure.Name = 'CRTN Calculator';
            app.HTML = uihtml(app.CRTNCalculatorUIFigure);
            app.HTML.HTMLSource = 'index.html';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            % Show the figure after all components are created
            app.CRTNCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = compiled_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CRTNCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CRTNCalculatorUIFigure)
        end
    end
end